classdef appMaquinaCDI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        MquinaClculoDiferencialeIntegralUIFigure  matlab.ui.Figure
        Menu                          matlab.ui.container.Menu
        RelatrioMenu                  matlab.ui.container.Menu
        ExitMenu                      matlab.ui.container.Menu
        TabGroup                      matlab.ui.container.TabGroup
        funcaoRealVariavelReal        matlab.ui.container.Tab
        pResultados                   matlab.ui.container.Panel
        eIntN                         matlab.ui.control.NumericEditField
        ResultadoIntegralAaBLabel     matlab.ui.control.Label
        SegundaDerivadaLabel          matlab.ui.control.Label
        IntegralLabel                 matlab.ui.control.Label
        DerivadaLabel                 matlab.ui.control.Label
        UITable                       matlab.ui.control.Table
        UIMostrador_2derivada         matlab.ui.control.UIAxes
        UIMostrador_integral          matlab.ui.control.UIAxes
        UIMostrador_derivada          matlab.ui.control.UIAxes
        grafico                       matlab.ui.control.UIAxes
        pDados                        matlab.ui.container.Panel
        derivadaOUintegral            matlab.ui.control.Switch
        IntegracoesNumericasBox       matlab.ui.container.ButtonGroup
        RegraTrapezios                matlab.ui.control.RadioButton
        RegraSimpson                  matlab.ui.control.RadioButton
        DerivacoesNumericasBox        matlab.ui.container.ButtonGroup
        DiferencasCentradas3          matlab.ui.control.RadioButton
        DiferencasRegressivas2        matlab.ui.control.RadioButton
        DiferencasRegressivas3        matlab.ui.control.RadioButton
        DiferencasProgressivas2       matlab.ui.control.RadioButton
        DiferencasProgressivas3       matlab.ui.control.RadioButton
        bAtualizar                    matlab.ui.control.Button
        CalculoDiferencialEIntegral   matlab.ui.container.Panel
        bLabel                        matlab.ui.control.Label
        eB                            matlab.ui.control.NumericEditField
        hLabel                        matlab.ui.control.Label
        eH                            matlab.ui.control.NumericEditField
        eA                            matlab.ui.control.NumericEditField
        aLabel                        matlab.ui.control.Label
        eF                            matlab.ui.control.EditField
        fxLabel                       matlab.ui.control.Label
        funcaoReal2VariaveisReaisXY   matlab.ui.container.Tab
        pResultados_2                 matlab.ui.container.Panel
        Grafico                       matlab.ui.control.UIAxes
        pDados_2                      matlab.ui.container.Panel
        eHarmonica                    matlab.ui.control.EditField
        DerivacoesNumericasGroup      matlab.ui.container.ButtonGroup
        DerivadaOrdemX                matlab.ui.control.RadioButton
        DerivadaOrdemY                matlab.ui.control.RadioButton
        bAtualizar_2                  matlab.ui.control.Button
        FuncaoReal2VariaveisReaisBox  matlab.ui.container.Panel
        YPanel                        matlab.ui.container.Panel
        eA_3                          matlab.ui.control.NumericEditField
        aLabel_3                      matlab.ui.control.Label
        bLabel_3                      matlab.ui.control.Label
        eB_3                          matlab.ui.control.NumericEditField
        hLabel_3                      matlab.ui.control.Label
        eH_3                          matlab.ui.control.NumericEditField
        XPanel                        matlab.ui.container.Panel
        eA_2                          matlab.ui.control.NumericEditField
        aLabel_2                      matlab.ui.control.Label
        bLabel_2                      matlab.ui.control.Label
        eB_2                          matlab.ui.control.NumericEditField
        hLabel_2                      matlab.ui.control.Label
        eH_2                          matlab.ui.control.NumericEditField
        eF_2                          matlab.ui.control.EditField
        fxyLabel                      matlab.ui.control.Label
        resultadoHarmonica            matlab.ui.control.UIAxes
    end


    methods (Access = public)

        function YN = isHarmonica(~,f)
            syms z(x,y)
            if (diff(f,x,2)+diff(f,y,2)==0)
                YN = 1;
            else
                YN = 0;
            end
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.RegraTrapezios.Enable = false;
            app.RegraSimpson.Enable = false;
            app.ResultadoIntegralAaBLabel.Visible = false;
            app.eIntN.Visible = false;
        end

        % Button pushed function: bAtualizar
        function bAtualizarButtonPushed(app, event)
            syms y(x)
            strF = app.eF.Value;
            f = @(x) eval(vectorize(strF));
            a = app.eA.Value;
            h = app.eH.Value;
            b = app.eB.Value;
            
            testeF = MException('Matlab:BadFunction','Introduza uma função em x');
            testeAReal=MException('MATLAB:MyAtualizar:badNReal','Valor de "a" não introduzido ou não real');
            testeBReal=MException('MATLAB:MyAtualizar:badNReal','Valor de "b" não introduzido ou não real'); 
            testeHReal=MException('MATLAB:MyAtualizar:badNReal','Valor de "h" não introduzido ou não real');
            testeAmaiorB=MException('MATLAB:BadFunction','Valor de "a" tem de ser MENOR que "b"');
            testeAigual = MException('MATLAB:BadFunction','Valor de "a" tem de ser DIFERENTE que "b"');
            
            try 
                dydxSym = diff(f(x));
                sedydxSym = diff(f(x),2);  %calcula s segunda derivada
                g =@(x) eval(vectorize(char(dydxSym)));
                o =@(x) eval(vectorize(char(sedydxSym)));
                
              %Verificações de testes 
              
                if ~(isscalar(a) && isreal(a))  %se A não for um numero ou se não for real  
                    app.eA.BackgroundColor = 'w';
                    app.eA.FontColor = 'r';
                    throw(testeAReal);
                else
                    app.eA.BackgroundColor = 'w';
                    app.eA.FontColor = 'k';
                end

                if ~(isscalar(b) && isreal(b)) %se B não for um numero ou se não for real
                    app.eB.BackgroundColor = 'w';
                    app.eB.FontColor = 'r';
                    throw(testeBReal);
                else
                    app.eB.BackgroundColor = 'w';
                    app.eB.FontColor = 'k';
                end
                if ~(isscalar(h) && isreal(h)) %se h não for um numero ou se não for real
                    app.eH.BackgroundColor = 'w';
                    app.eH.FontColor = 'r';
                    throw(testeHReal);
                else
                    app.eH.BackgroundColor = 'w';
                    app.eH.FontColor = 'k';
                end
                
                if(a>b || a==b)
                    app.eA.BackgroundColor = 'w';
                    app.eA.FontColor = 'r';
                    app.eB.BackgroundColor = 'w';
                    app.eB.FontColor = 'r';
                    if(a == b)
                        throw(testeAigual)
                    end
                    throw(testeAmaiorB);
                else
                    app.eA.BackgroundColor = 'w';
                    app.eA.FontColor = 'k';
                    app.eB.BackgroundColor = 'w';
                    app.eB.FontColor = 'k';
                 end

              %%
                escolhamenu = app.derivadaOUintegral.Value;
                escolha1 = app.DerivacoesNumericasBox.SelectedObject.Text;
                if(escolhamenu == "Derivações Numéricas")
                    switch escolha1
                        case 'Diferenças Progressivas 2 Pontos'
                            [x,y,dydxDFP] = DFP(f,a,b,h);
                            dydxExata = g(x);
                            sedydxExata = o(x);
                            erroDFP = abs(dydxExata -dydxDFP);
                            tabela = array2table([x.',y.',dydxExata.', dydxDFP.',erroDFP.',sedydxExata.']);
                            app.UITable.Data = tabela;
                            app.UITable.ColumnName = ({'x','y','dydxExata','dydxDFP','erroDFP','2ªDerivada'});
    
                            plot(app.grafico,x,y,"-r+")
                            hold(app.grafico,"on")
                            plot(app.grafico,x,dydxExata,"Color","#D94AC4","Marker","square")
                            plot(app.grafico,x,dydxDFP,"Color","#2F4F4F","Marker","pentagram")
                            plot(app.grafico,x,sedydxExata,"Color","#006400","Marker","diamond")
                            hold(app.grafico,"on")

                            legend(app.grafico,'y','dydxExato','dxdyDFP','2ª derivada')
    
    
                        case 'Diferenças Regressivas 2 Pontos'
                            [x,y,dydxDFR] = DFR(f,a,b,h);
                            dydxExata = g(x);
                            sedydxExata = o(x);
                            erroDFR = abs(dydxExata -dydxDFR);
                            tabela = array2table([x.',y.',dydxExata.', dydxDFR.',erroDFR.',sedydxExata.']);
                            app.UITable.Data = tabela;
                            app.UITable.ColumnName = ({'x','y','dydxExata','dydxDFR','erroDFR','2ªDerivada'});
    
                            plot(app.grafico,x,y,"-r+")
                            hold(app.grafico,"on")
                            plot(app.grafico,x,dydxExata,"Color","#D94AC4","Marker","square")
                            plot(app.grafico,x,dydxDFR,"Color","#2F4F4F","Marker","pentagram")
                            plot(app.grafico,x,sedydxExata,"Color","#006400","Marker","diamond")
                            hold(app.grafico,"on")
    
    
                            legend(app.grafico,'y','dydxExato','dxdyDFR','2ª derivada')
    
                        case 'Diferenças Progressivas 3 Pontos'
                            [x,y,dydxDFP] = DFP3(f,a,b,h);
                            dydxExata = g(x);
                            sedydxExata = o(x);
                            erroDFP = abs(dydxExata -dydxDFP);
                            
                            tabela = array2table([x.',y.',dydxExata.', dydxDFP.',erroDFP.',sedydxExata.']);
                            app.UITable.Data = tabela;
                            app.UITable.ColumnName = ({'x','y','dydxExata','dydxDFP3','erroDFP3','2ª derivada'});
    
                            plot(app.grafico,x,y,"-r+")
                            hold(app.grafico,"on")
                            plot(app.grafico,x,dydxExata,"Color","#D94AC4","Marker","square")
                            plot(app.grafico,x,dydxDFP,"Color","#2F4F4F","Marker","pentagram")
                            plot(app.grafico,x,sedydxExata,"Color","#006400","Marker","diamond")
                            hold(app.grafico,"on")
    
    
                            legend(app.grafico,'y','dydxExato','dxdyDFR','2ª derivada')
    
                        case 'Diferenças Regressivas 3 Pontos'
                            [x,y,dydxDFR] = DFR3(f,a,b,h);
                            dydxExata = g(x);
                            sedydxExata = o(x);
                            erroDFP = abs(dydxExata -dydxDFR);
                            tabela = array2table([x.',y.',dydxExata.', dydxDFR.',erroDFP.',sedydxExata.']);
                            app.UITable.Data = tabela;
                            app.UITable.ColumnName = ({'x','y','dydxExata','dydxDFR','erroDFR','2ª derivada'});
    
                            plot(app.grafico,x,y,"-r+")
                            hold(app.grafico,"on")
                            plot(app.grafico,x,dydxExata,"Color","#D94AC4","Marker","square")
                            plot(app.grafico,x,dydxDFR,"Color","#2F4F4F","Marker","pentagram")
                            plot(app.grafico,x,sedydxExata,"Color","#006400","Marker","diamond")
                            hold(app.grafico,"on")
                            
    
                            legend(app.grafico,'y','dydxExato','dxdyDFR','2ª derivada')
    
                        case 'Diferenças Centradas 3 Pontos'
                            [x,y,dydxDFC] = DFC(f,a,b,h);
                            dydxExata = g(x);
                            sedydxExata = o(x);
                            erroDFC = abs(dydxExata -dydxDFC);
                            tabela = array2table([x.',y.',dydxExata.', dydxDFC.',erroDFC.',sedydxExata.']);
                            app.UITable.Data = tabela;
                            app.UITable.ColumnName = ({'x','y','dydxExata','dydxDFC','erroDCR','2ª derivada'});
    
                            plot(app.grafico,x,y,"-r+")
                            hold(app.grafico,"on")
                            plot(app.grafico,x,dydxExata,"Color","#D94AC4","Marker","square")
                            plot(app.grafico,x,dydxDFC,"Color","#2F4F4F","Marker","pentagram")
                            plot(app.grafico,x,sedydxExata,"Color","#006400","Marker","diamond")
                            hold(app.grafico,"on")
    
                            legend(app.grafico,'y','dydxExato','dxdyDFC','2ª derivada')
                    end
                else
                
                
                    n = (b-a)/h;
                    escolha2 = app.IntegracoesNumericasBox.SelectedObject.Text;
    
                    switch escolha2
                        case 'Regra dos Trapézios'
                            T = RTrapezios(f,a,b,n);
                            app.eIntN.Value = T;
                            [x,y,~] = DFP(f,a,b,h);
                            area(app.grafico,x,y,"FaceColor","b","FaceAlpha",0.2)
                            legend(app.grafico,'Area Integral')
                            
                            
                        case 'Regra de Simpson'
                            S = RSimpson(f,a,b,n);
                            app.eIntN.Value = S;
                            [x,y,~] = DFP(f,a,b,h);
                            area(app.grafico,x,y,"FaceColor","b","FaceAlpha",0.2)
                            legend(app.grafico,'Area Integral')
                    end
                end
                    sExata = int(f(sym('x')));
                    %q = integral(f,a,b);
    
                    cla(app.UIMostrador_derivada)
                    cla(app.UIMostrador_integral)
                    cla(app.UIMostrador_2derivada)
                    formaLatex =['$','\frac{dy}{dx}=',latex(dydxSym),'$'];
                    text(app.UIMostrador_derivada,0.1,0.5,formaLatex,"Interpreter","latex","FontSize",18);
                    formaLatex =['$','\int_a^b',app.eF.Value,'=',latex(sExata),'$'];
                    text(app.UIMostrador_integral,0.1,0.5,formaLatex,"Interpreter","latex","FontSize",18);
                    formaLatex =['$','\frac{d^2y}{dx^2}=',latex(dydxSym),'$'];
                    text(app.UIMostrador_2derivada,0.1,0.5,formaLatex,"Interpreter","latex","FontSize",18);
    
                    hold(app.grafico,"off")
    
                catch ME
                    errordlg(ME.message,"ERRO","modal")
            end


        end

        % Button pushed function: bAtualizar_2
        function bAtualizar_2ButtonPushed(app, event)

            syms x y f(x,y)

            strFxy = app.eF_2.Value;
            f =@(x,y) eval(vectorize(strFxy));

            partialdydxSymx = diff(f(x,y),x);
            partialdydxSymy = diff(f(x,y),y);

            a = app.eA_2.Value;
            h1 = app.eH_2.Value;
            b = app.eB_2.Value;
            a2 = app.eA_3.Value;
            h2 = app.eH_3.Value;
            b2 = app.eB_3.Value;
            
            testeF = MException('Matlab:BadFunction','Introduza uma função em x');
            testeAReal = MException('MATLAB:MyAtualizar:badNReal','Valor de "a" não introduzido ou não real');
            testeBReal = MException('MATLAB:MyAtualizar:badNReal','Valor de "b" não introduzido ou não real'); 
            testeHReal = MException('MATLAB:MyAtualizar:badNReal','Valor de "h" não introduzido ou não real');
            testeAmaiorB = MException('MATLAB:BadFunction','Valor de "a" tem de ser MENOR que "b"');
            testeAigual = MException('MATLAB:BadFunction','Valor de "a" tem de ser DIFERENTE que "b"');
            try
       %Verificações de testes
                %try
                %  f(sym('x'));
                %catch
                %  throw(testeF);
                % end

                if ~(isscalar(a) && isreal(a))  %se A não for um numero ou se não for real
                    app.eA_2.BackgroundColor = 'w';
                    app.eA_2.FontColor = 'r';
                    throw(testeAReal);
                else
                    app.eA_2.BackgroundColor = 'w';
                    app.eA_2.FontColor = "k";
                end

                if ~(isscalar(b) && isreal(b)) %se B não for um numero ou se não for real
                    app.eB_2.BackgroundColor = 'w';
                    app.eB_2.FontColor = 'r';
                    throw(testeBReal);
                else
                    app.eB_2.BackgroundColor = 'w';
                    app.eB_2.FontColor = 'k';
                end
                if ~(isscalar(h1) && isreal(h1)) %se h não for um numero ou se não for real
                    app.eH_2.BackgroundColor = 'w';
                    app.eH_2.FontColor = 'r';
                    throw(testeHReal);
                else
                    app.eH_2.BackgroundColor = 'w';
                    app.eH_2.FontColor = 'k';
                end

                if(a>b || a==b) % se a>b ou a == b
                    app.eA_2.BackgroundColor = 'w';
                    app.eA_2.FontColor = 'r';
                    app.eB_2.BackgroundColor = 'w';
                    app.eB_2.FontColor = 'r';
                    
                    if(a == b)
                        throw(testeAigual)
                    end
                    throw(testeAmaiorB);
                else
                    app.eA_2.BackgroundColor = 'w';
                    app.eA_2.FontColor = 'k';
                    app.eB_2.BackgroundColor = 'w';
                    app.eB_2.FontColor = 'k';
                end
                 
              %% 2-Box
              
              if ~(isscalar(a2) && isreal(a2))  %se A não for um numero ou se não for real
                  app.eA_3.BackgroundColor = 'w';
                  app.eA_3.FontColor = 'r';
                  throw(testeAReal);
              else
                  app.eA_3.BackgroundColor = 'w';
                  app.eA_3.FontColor = 'k';
              end

              if ~(isscalar(b2) && isreal(b2)) %se B não for um numero ou se não for real
                  app.eB_3.BackgroundColor = 'w';
                  app.eB_3.FontColor = 'r';
                  throw(testeBReal);
              else
                  app.eB_3.BackgroundColor = 'w';
                  app.eB_3.FontColor = 'k';
              end
              if ~(isscalar(h2) && isreal(h2)) %se h não for um numero ou se não for real
                  app.eH_3.BackgroundColor = 'w';
                  app.eH_3.FontColor = 'r';
                  throw(testeHReal);
              else
                  app.eH_3.BackgroundColor = 'w';
                  app.eH_3.FontColor = 'k';
              end

              if(a2>b2 || a2==b2)
                  app.eA_3.BackgroundColor = 'w';
                  app.eA_3.FontColor = 'r';
                  app.eB_3.BackgroundColor = 'w';
                  app.eB_3.FontColor = 'r';
                  if(a2 == b2)
                        throw(testeAigual)
                  end
                  throw(testeAmaiorB);
              else
                  app.eA_3.BackgroundColor = 'w';
                  app.eA_3.FontColor = 'k';
                  app.eB_3.BackgroundColor = 'w';
                  app.eB_3.FontColor = 'k';
              end
              
            %%
              [x,y] = meshgrid(a:h1:b, a2:h2:b2);
              z = f(x,y);
              mesh(app.Grafico, x,y,z)

              if isHarmonica(app,f)
                  strHarmonica = "A FUNÇÃO F É HARMÓNICA";
                  corHarmonica = "g";  %coloca a verde se e harmonica
              else
                  strHarmonica = "A FUNÇÃO F NÃO É HARMÓNICA";
                  corHarmonica = "r";  %coloca a vermelho se nao
              end
              
              app.eHarmonica.Value = strHarmonica;
              app.eHarmonica.FontColor = corHarmonica;
              
              escolha3 = app.DerivacoesNumericasGroup.SelectedObject.Text;
              switch escolha3
                  case 'Derivada parcial ordem a x'
                      cla(app.resultadoHarmonica)
                      formaLatex =['$','\frac{\partial f}{\partial x}=\frac{\partial ',strFxy,'}{\partial x}=',latex(partialdydxSymx),'$'];
                      text(app.resultadoHarmonica,0.1,0.5,formaLatex,"Interpreter","latex","FontSize",18);
                  case 'Derivada parcial ordem a y'
                      cla(app.resultadoHarmonica)
                      formaLatex =['$','\frac{\partial f}{\partial y}=\frac{\partial ',strFxy,'}{\partial y}=',latex(partialdydxSymy),'$'];
                      text(app.resultadoHarmonica,0.1,0.5,formaLatex,"Interpreter","latex","FontSize",18);
              end
              
              
            catch ME
                errordlg(ME.message,"ERRO","modal")
            end
            

        end

        % Value changed function: derivadaOUintegral
        function derivadaOUintegralValueChanged(app, event)
            value = app.derivadaOUintegral.Value;
            if(value == "Derivada")
                app.UITable.Visible = true;
                app.DiferencasProgressivas2.Enable = true;
                app.DiferencasProgressivas3.Enable = true;
                app.DiferencasRegressivas2.Enable = true;
                app.DiferencasRegressivas3.Enable = true;
                app.DiferencasCentradas3.Enable = true;
                app.RegraTrapezios.Enable = false;
                app.RegraSimpson.Enable = false;
                app.ResultadoIntegralAaBLabel.Visible = false;
                app.eIntN.Visible = false;
            end
            if(value == "Integral")
                app.UITable.Visible = false;
                app.DiferencasProgressivas2.Enable = false;
                app.DiferencasProgressivas3.Enable = false;
                app.DiferencasRegressivas2.Enable = false;
                app.DiferencasRegressivas3.Enable = false;
                app.DiferencasCentradas3.Enable = false;
                app.RegraTrapezios.Enable = true;
                app.RegraSimpson.Enable = true;
                app.ResultadoIntegralAaBLabel.Visible = true;
                app.eIntN.Visible = true;
            end
        end

        % Callback function
        function SobreNsMenuSelected(app, event)
            run("aus.mlapp")
        end

        % Menu selected function: ExitMenu
        function ExitMenuSelected(app, event)
            SN = questdlg('Deseja mesmo fechar a aplicação?','SAIR','Sim','Não','Não');
            if strcmp(SN,'Sim')
                delete(app.MquinaClculoDiferencialeIntegralUIFigure)
            end
        end

        % Menu selected function: RelatrioMenu
        function RelatrioMenuSelected(app, event)
            winopen('relatorio3.docx')
        end

        % Callback function
        function ButtonPushed(app, event)
            app.eF.BackgroundColor = "0.07,0.07,0.07";
            app.eF.FontColor = "0.73,0.73,0.73";
            app.eA_2.BackgroundColor = "0.07,0.07,0.07";
            app.eA_2.FontColor = "0.73,0.73,0.73";
            app.eB_2.BackgroundColor = "0.07,0.07,0.07";
            app.eB_2.FontColor = "0.73,0.73,0.73";
            app.eH_2.BackgroundColor = "0.07,0.07,0.07";
            app.eH_2.FontColor = "0.73,0.73,0.73";
            %%2 caixa
            app.eA_3.BackgroundColor = "0.07,0.07,0.07";
            app.eA_3.FontColor = "0.73,0.73,0.73";
            app.eH_3.BackgroundColor = "0.07,0.07,0.07";
            app.eH_3.FontColor = "0.73,0.73,0.73";
            app.eB_3.BackgroundColor = "0.07,0.07,0.07";
            app.eB_3.FontColor = "0.73,0.73,0.73";
            
          %Valores
            app.eF.Value = 'x^2+y^2';
            app.eA_2.Value = 0;
            app.eH_2.Value = 0.1;
            app.eB_2.Value = pi*2;
            app.eA_3.Value = 0;
            app.eH_3.Value = 0.1;
            app.eB_3.Value = pi*2;
        end

        % Callback function
        function Button_2Pushed(app, event)
            app.eF.BackgroundColor = "0.07,0.07,0.07";
            app.eF.FontColor = "0.73,0.73,0.73";
            app.eA.BackgroundColor = "0.07,0.07,0.07";
            app.eA.FontColor = "0.73,0.73,0.73";
            app.eB.BackgroundColor = "0.07,0.07,0.07";
            app.eB.FontColor = "0.73,0.73,0.73";
            app.eH.BackgroundColor = "0.07,0.07,0.07";
            app.eH.FontColor = "0.73,0.73,0.73";
          %Valores
            app.eF.Value = 'sin(x)';
            app.eA.Value = 0;
            app.eH.Value = 0.1;
            app.eB.Value = pi*2;
        end

        % Selection change function: TabGroup
        function TabGroupSelectionChanged(app, event)
            selectedTab = app.TabGroup.SelectedTab;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.MquinaClculoDiferencialeIntegralUIFigure = uifigure('Visible', 'off');
            app.MquinaClculoDiferencialeIntegralUIFigure.Name = 'Máquina Cálculo Diferencial e Integral';
            app.Menu = uimenu(app.MquinaClculoDiferencialeIntegralUIFigure);
            app.Menu.Text = '| Menu';
            app.RelatrioMenu = uimenu(app.Menu);
            app.RelatrioMenu.MenuSelectedFcn = createCallbackFcn(app, @RelatrioMenuSelected, true);
            app.RelatrioMenu.Text = '| Relatório';
            app.ExitMenu = uimenu(app.Menu);
            app.ExitMenu.MenuSelectedFcn = createCallbackFcn(app, @ExitMenuSelected, true);
            app.ExitMenu.Text = '| Exit';
            app.TabGroup = uitabgroup(app.MquinaClculoDiferencialeIntegralUIFigure);
            app.TabGroup.SelectionChangedFcn = createCallbackFcn(app, @TabGroupSelectionChanged, true);
            app.funcaoRealVariavelReal = uitab(app.TabGroup);
            app.funcaoRealVariavelReal.Title = 'Função real de variável real y = f(x)';
            app.pDados = uipanel(app.funcaoRealVariavelReal);
            app.CalculoDiferencialEIntegral = uipanel(app.pDados);
            app.CalculoDiferencialEIntegral.Title = 'Cálculo Diferencial e Integral ';
            app.fxLabel = uilabel(app.CalculoDiferencialEIntegral);
            app.fxLabel.Text = 'f(x) =';
            app.eF = uieditfield(app.CalculoDiferencialEIntegral, 'text');
            app.eF.Value = 'sin(x)';
            app.aLabel = uilabel(app.CalculoDiferencialEIntegral);
            app.aLabel.Text = 'a';
            app.eA = uieditfield(app.CalculoDiferencialEIntegral, 'numeric');
            app.eH = uieditfield(app.CalculoDiferencialEIntegral, 'numeric');
            app.eH.Value = 0.1;
            app.hLabel = uilabel(app.CalculoDiferencialEIntegral);
            app.hLabel.Text = 'h';
            app.eB = uieditfield(app.CalculoDiferencialEIntegral, 'numeric');
            app.eB.Value = 6.28318530717959;
            app.bLabel = uilabel(app.CalculoDiferencialEIntegral);
            app.bLabel.Text = 'b';
            app.bAtualizar = uibutton(app.pDados, 'push');
            app.bAtualizar.ButtonPushedFcn = createCallbackFcn(app, @bAtualizarButtonPushed, true);
            app.bAtualizar.Text = 'Atualizar';
            app.DerivacoesNumericasBox = uibuttongroup(app.pDados);
            app.DerivacoesNumericasBox.Title = 'Derivações Numéricas';
            app.DiferencasProgressivas3 = uiradiobutton(app.DerivacoesNumericasBox);
            app.DiferencasProgressivas3.Text = 'Diferenças Progressivas 3 Pontos';
            app.DiferencasProgressivas2 = uiradiobutton(app.DerivacoesNumericasBox);
            app.DiferencasProgressivas2.Text = 'Diferenças Progressivas 2 Pontos';
            app.DiferencasProgressivas2.Value = true;
            app.DiferencasRegressivas3 = uiradiobutton(app.DerivacoesNumericasBox);
            app.DiferencasRegressivas3.Text = 'Diferenças Regressivas 3 Pontos';
            app.DiferencasRegressivas2 = uiradiobutton(app.DerivacoesNumericasBox);
            app.DiferencasRegressivas2.Text = 'Diferenças Regressivas 2 Pontos';
            app.DiferencasCentradas3 = uiradiobutton(app.DerivacoesNumericasBox);
            app.DiferencasCentradas3.Text = 'Diferenças Centradas 3 Pontos';
            app.IntegracoesNumericasBox = uibuttongroup(app.pDados);
            app.IntegracoesNumericasBox.Title = 'Integrações Numéricas';
            app.RegraSimpson = uiradiobutton(app.IntegracoesNumericasBox);
            app.RegraSimpson.Text = 'Regra de Simpson';
            app.RegraTrapezios = uiradiobutton(app.IntegracoesNumericasBox);
            app.RegraTrapezios.Text = 'Regra dos Trapézios';
            app.RegraTrapezios.Value = true;
            app.derivadaOUintegral = uiswitch(app.pDados, 'slider');
            app.derivadaOUintegral.Items = {'Derivações Numéricas', 'Integrações Numéricas'};
            app.derivadaOUintegral.ValueChangedFcn = createCallbackFcn(app, @derivadaOUintegralValueChanged, true);
            app.derivadaOUintegral.Value = 'Derivações Numéricas';
            app.pResultados = uipanel(app.funcaoRealVariavelReal);
            app.grafico = uiaxes(app.pResultados);
            title(app.grafico, {'Gráfico - Função Real de Variável Real'; ''})
            xlabel(app.grafico, 'X')
            ylabel(app.grafico, 'Y')
            zlabel(app.grafico, 'Z')
            app.UIMostrador_derivada = uiaxes(app.pResultados);
            app.UIMostrador_derivada.XTick = [];
            app.UIMostrador_derivada.YTick = [];
            app.UIMostrador_integral = uiaxes(app.pResultados);
            app.UIMostrador_integral.XTick = [];
            app.UIMostrador_integral.YTick = [];
            app.UIMostrador_2derivada = uiaxes(app.pResultados);
            app.UIMostrador_2derivada.XTick = [];
            app.UIMostrador_2derivada.YTick = [];
            app.UITable = uitable(app.pResultados);
            app.UITable.ColumnName = '';
            app.UITable.RowName = {};
            app.DerivadaLabel = uilabel(app.pResultados);
            app.DerivadaLabel.Text = 'Derivada';
            app.IntegralLabel = uilabel(app.pResultados);
            app.IntegralLabel.Text = 'Integral';
            app.SegundaDerivadaLabel = uilabel(app.pResultados);
            app.SegundaDerivadaLabel.Text = 'Segunda Derivada';
            app.ResultadoIntegralAaBLabel = uilabel(app.pResultados);
            app.ResultadoIntegralAaBLabel.Text = 'Resultado Integral A a B';
            app.eIntN = uieditfield(app.pResultados, 'numeric');
            app.eIntN.Editable = 'off';
            app.funcaoReal2VariaveisReaisXY = uitab(app.TabGroup);
            app.funcaoReal2VariaveisReaisXY.Title = 'Função real de 2 variáveis reais z = f(x,y)';
            app.pDados_2 = uipanel(app.funcaoReal2VariaveisReaisXY);
            app.resultadoHarmonica = uiaxes(app.pDados_2);
            app.resultadoHarmonica.XTick = [];
            app.resultadoHarmonica.YTick = [];
            app.FuncaoReal2VariaveisReaisBox = uipanel(app.pDados_2);
            app.FuncaoReal2VariaveisReaisBox.Title = 'Função Real de 2 Variáveis Reais';
            app.fxyLabel = uilabel(app.FuncaoReal2VariaveisReaisBox);
            app.fxyLabel.Text = 'f(x,y) = ';
            app.eF_2 = uieditfield(app.FuncaoReal2VariaveisReaisBox, 'text');
            app.eF_2.Value = 'x^2+y^2';
            app.XPanel = uipanel(app.FuncaoReal2VariaveisReaisBox);
            app.XPanel.Title = 'X';
            app.eH_2 = uieditfield(app.XPanel, 'numeric');
            app.eH_2.Value = 0.1;
            app.hLabel_2 = uilabel(app.XPanel);
            app.hLabel_2.Text = 'h';
            app.eB_2 = uieditfield(app.XPanel, 'numeric');
            app.eB_2.Value = 6.28318530717959;
            app.bLabel_2 = uilabel(app.XPanel);
            app.bLabel_2.Text = 'b';
            app.aLabel_2 = uilabel(app.XPanel);
            app.aLabel_2.Text = 'a';
            app.eA_2 = uieditfield(app.XPanel, 'numeric');
            app.YPanel = uipanel(app.FuncaoReal2VariaveisReaisBox);
            app.YPanel.Title = 'Y';
            app.eH_3 = uieditfield(app.YPanel, 'numeric');
            app.eH_3.Value = 0.1;
            app.hLabel_3 = uilabel(app.YPanel);
            app.hLabel_3.Text = 'h';
            app.eB_3 = uieditfield(app.YPanel, 'numeric');
            app.eB_3.Value = 6.28318530717959;
            app.bLabel_3 = uilabel(app.YPanel);
            app.bLabel_3.Text = 'b';
            app.aLabel_3 = uilabel(app.YPanel);
            app.aLabel_3.Text = 'a';
            app.eA_3 = uieditfield(app.YPanel, 'numeric');
            app.bAtualizar_2 = uibutton(app.pDados_2, 'push');
            app.bAtualizar_2.ButtonPushedFcn = createCallbackFcn(app, @bAtualizar_2ButtonPushed, true);
            app.bAtualizar_2.Text = 'Atualizar';
            app.DerivacoesNumericasGroup = uibuttongroup(app.pDados_2);
            app.DerivacoesNumericasGroup.Title = 'Derivações Numéricas';
            app.DerivadaOrdemY = uiradiobutton(app.DerivacoesNumericasGroup);
            app.DerivadaOrdemY.Text = 'Derivada parcial ordem a y';
            app.DerivadaOrdemX = uiradiobutton(app.DerivacoesNumericasGroup);
            app.DerivadaOrdemX.Text = 'Derivada parcial ordem a x';
            app.DerivadaOrdemX.Value = true;
            app.eHarmonica = uieditfield(app.pDados_2, 'text');
            app.eHarmonica.Editable = 'off';
            app.pResultados_2 = uipanel(app.funcaoReal2VariaveisReaisXY);
            app.Grafico = uiaxes(app.pResultados_2);
            title(app.Grafico, {'Gráfico - Função Real de 2 Variáveis Reais'; ''})
            xlabel(app.Grafico, 'X')
            ylabel(app.Grafico, 'Y')
            zlabel(app.Grafico, 'Z')
            % Show the figure after all components are created
            app.MquinaClculoDiferencialeIntegralUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = appMaquinaCDI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.MquinaClculoDiferencialeIntegralUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.MquinaClculoDiferencialeIntegralUIFigure)
        end
    end
end