classdef appMaquinaPVI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure       matlab.ui.Figure
        GridLayout     matlab.ui.container.GridLayout
        LeftPanel      matlab.ui.container.Panel
        DropDown       matlab.ui.control.DropDown
        DropDownLabel  matlab.ui.control.Label
        DadosPVI_3     matlab.ui.container.Panel
        Lamp_n         matlab.ui.control.Lamp
        eN             matlab.ui.control.NumericEditField
        aLabel_9       matlab.ui.control.Label
        Lamp_b         matlab.ui.control.Lamp
        eB             matlab.ui.control.NumericEditField
        aLabel_2       matlab.ui.control.Label
        eA             matlab.ui.control.NumericEditField
        aLabel         matlab.ui.control.Label
        Lamp_a         matlab.ui.control.Lamp
        DadosPVI_2     matlab.ui.container.Panel
        Lamp_v0        matlab.ui.control.Lamp
        Lamp_u0        matlab.ui.control.Lamp
        e_V            matlab.ui.control.NumericEditField
        aLabel_8       matlab.ui.control.Label
        e_U            matlab.ui.control.NumericEditField
        aLabel_7       matlab.ui.control.Label
        reset          matlab.ui.control.Button
        bAtualizar     matlab.ui.control.Button
        bgMetodos      matlab.ui.container.ButtonGroup
        rbHeun         matlab.ui.control.RadioButton
        rbTodos        matlab.ui.control.RadioButton
        rbRK4          matlab.ui.control.RadioButton
        rbRK2          matlab.ui.control.RadioButton
        rbEuler        matlab.ui.control.RadioButton
        DadosPVI       matlab.ui.container.Panel
        ftyLabel_2     matlab.ui.control.Label
        eG             matlab.ui.control.EditField
        Lamp_g         matlab.ui.control.Lamp
        Lamp_f         matlab.ui.control.Lamp
        eF             matlab.ui.control.EditField
        ftyLabel       matlab.ui.control.Label
        RightPanel     matlab.ui.container.Panel
        UITable        matlab.ui.control.Table
        UIAxes         matlab.ui.control.UIAxes
        Menu           matlab.ui.container.Menu
        AjudaMenu      matlab.ui.container.Menu
        RelatrioMenu   matlab.ui.container.Menu
        SairMenu       matlab.ui.container.Menu
        AutoresMenu    matlab.ui.container.Menu
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            bAtualizarButtonPushed(app);
        end

        % Button pushed function: bAtualizar
        function bAtualizarButtonPushed(app, event)
   
                        syms u(t) v(t)
                
            f=@(t,u,v) v;                                         
            strG = app.eG.Value;        % Obtem a string existente no edit text 'eF' e coloca em strF
            g=@(t,u,v) eval(vectorize(strG)); 
            
            a = app.eA.Value;           
            b = app.eB.Value; 
            n = app.eN.Value;          
            u0 = app.e_U.Value;
            v0 = app.e_V.Value;

            
            escolhabgMetodos = app.bgMetodos.SelectedObject;
            metodoSelecionado = find([app.rbEuler,...
                                     app.rbHeun...
                                     app.rbRK2,...
                                     app.rbRK4,... 
                                     app.rbTodos]==escolhabgMetodos); 


            erroFuncao = MException("MATLAB:bAtualizar:badF",...
                                    "Introduza uma função em t,u e v");


          try

                try
                    gTeste=g(sym('t'),sym('u'),sym('v'));
                    app.Lamp_g.Color = "g";
                catch ME
                    app.Lamp_g.Color = "r";                  
                    throw(erroFuncao)
                end

               if( a >= b || n <= 1 || isinteger(n))
                    
                    
                        if(a>=b)
                            errordlg('O valor de b tem de ser superior ao valor de a','Erro a>b','modal');
                            app.Lamp_a.Color = "r";
                            app.Lamp_b.Color = "r";
                        else
                            app.Lamp_a.Color = "g";
                            app.Lamp_b.Color = "g";
            
                        end

                        if(n < 2)
                             errordlg('O valor de n tem de ser maior ou igual a 2','Erro de n', 'modal');
                             app.Lamp_n.Color = "r";

                        else
                            app.Lamp_n.Color = "g";
                        end                
                       if(isaninteger(n))
                           errordlg('O valor de n tem de ser um inteiro','Erro','modal');
                           app.Lamp_n.Color = "r";
                       else
                           app.Lamp_n.Color = "g";
                       end

               else


                eqns = [diff(u,t) == v,diff(v,t) == -16*u];
                ci = [u(0)==u0,v(0)==v0];
                [uSolucaoU,uSolucaoV] = dsolve(eqns,ci);


               linear = isempty(uSolucaoU);

               if(linear == 0)
                   msgbox('linear');
               else
                   msgbox('não linear');
               end


               m =@(t) eval(vectorize(char(uSolucaoU)));


                switch metodoSelecionado
                    case 1
                        [t,yEuler,~] = MEulerSED(f,g,a,b,n,u0,v0);
                        
                        if(linear == 0)
                            yExata = m(t);
                                                      
                            plot(app.UIAxes,t,yExata,"-b")
                            hold(app.UIAxes,"on")
                                                        
                            erroEuler = yEuler - yExata;
                            plot(app.UIAxes,t,yEuler,"-g*") 
     
                            legenda = {'Exata','Euler'};
    
                            tabela = [t.',yExata.',yEuler.',erroEuler.'];
                            nomeColunas = {'t','Exata','Euler','erroEuler'};
    
                                                  
                        else
                                
                            plot(app.UIAxes,t, yEuler, '-g+')
                            legenda = {'Euler'};
                            tabela = [t.',yEuler.'];
                            nomeColunas = {'t','Euler'};


                        end
        
    
                    case 2
                         [t,yHeun,~]=MHeunSED(f,g,a,b,n,u0,v0);
                           
                         if(linear == 0)
                            
                            yExata = m(t);
    
                            plot(app.UIAxes,t,yExata,"-b")
                            hold(app.UIAxes,"on")
                                              
                            erroHeun = yHeun - yExata;
                            plot(app.UIAxes,t,yHeun,"-r*") 
     
                            legenda = {'Exata','Heun'};
    
                            tabela = [t.',yExata.',yHeun.',erroHeun.'];
                            nomeColunas = {'t','Exata','Heun','erroHeun'};
    
                                                  
                        else
    
                            plot(app.UIAxes,t, yHeun, '-g+')
                            legenda = {'Heun'};
    
                            tabela = [t.',yHeun.'];
                            nomeColunas = {'t','Heun'};


                         end

                    case 3
                            
                        [t,yRK2,~] = MRK2SED(f,g,a,b,n,u0,v0);

                         if(linear == 0)
                            
                            yExata = m(t);
    
                            plot(app.UIAxes,t,yExata,"-b")
                            hold(app.UIAxes,"on")
                                                      
                            erroRK2 = yRK2 - yExata;
                            plot(app.UIAxes,t,yRK2,"-k*") 
     
                            legenda = {'Exata','RK2'};
    
                            tabela = [t.',yExata.',yRK2.',erroRK2.'];
                            nomeColunas = {'t','Exata','RK2','erroRK2'};
    
                                                  
                        else
    
                            plot(app.UIAxes,t, yRK2, '-g+')
                            legenda = {'RK2'};
    
                            tabela = [t.',yRK2.'];
                            nomeColunas = {'t','RK2'};
                         end
    
                    case 4
                          [t,yRK4,~] = MRK4SED(f,g,a,b,n,u0,v0);
                          
                          if(linear == 0)
                            
                            yExata = m(t);
    
                            plot(app.UIAxes,t,yExata,"-b")
                            hold(app.UIAxes,"on")
                                                        
                            erroRK4 = yRK4 - yExata;
                            plot(app.UIAxes,t,yRK4,"-c*") 
     
                            legenda = {'Exata','RK4'};
    
                            tabela = [t.',yExata.',yRK4.',erroRK4.'];
                            nomeColunas = {'t','Exata','RK4','erroRK4'};
    
                                                  
                        else
    
                            plot(app.UIAxes,t, yRK4, '-g+')
                            legenda = {'RK4'};
    
                            tabela = [t.',yRK4.'];
                            nomeColunas = {'t','RK4'};
                         end
   
                    
                    case 5
                        [t,yEuler,~] = MEulerSED(f,g,a,b,n,u0,v0);
                        [t,yHeun,~] = MHeunSED(f,g,a,b,n,u0,v0);
                        [t,yRK2,~] = MRK2SED(f,g,a,b,n,u0,v0);
                        [t,yRK4,~] = MRK4SED(f,g,a,b,n,u0,v0);

                        if(linear == 0)
                             
                             yExata = m(t);
    
                             erroEuler = abs(yExata-yEuler);
                             erroHeun = abs(yExata-yHeun);
                             erroRK2 = abs(yExata-yRK2);
                             erroRK4 = abs(yExata-yRK4);
                            
                    
                             plot(app.UIAxes,t,yExata,"-b")
                             hold(app.UIAxes,"on")
                             
                             plot(app.UIAxes,t,yEuler,"-g")
                             plot(app.UIAxes,t,yHeun,"-m") 
                             plot(app.UIAxes,t,yRK2,"-k") 
                             plot(app.UIAxes,t,yRK4,"-c") 
    
                             legenda = {'Exata','Euler','Heun','RK2','RK4'};
                             tabela = [t.',yExata.',yEuler.',yHeun.',yRK2.',yRK4.',erroEuler.',erroHeun.',erroRK2.',erroRK4.'];
                             nomeColunas = {'t','Exata','Euler','Heun','RK2','RK4',...
                                            'erroEuler','erroHeun','erroRK2','erroRK4'};
                        else

                             
                             plot(app.UIAxes,t,yEuler,"-g+")
                             hold(app.UIAxes,"on")
                             plot(app.UIAxes,t,yHeun,"-m*") 
                             plot(app.UIAxes,t,yRK2,"-k+") 
                             plot(app.UIAxes,t,yRK4,"-c*") 
    
                             legenda = {'Euler','Heun','RK2','RK4'};
                             tabela = [t.',yEuler.',yHeun.',yRK2.',yRK4.'];
                             nomeColunas = {'t','Euler','Heun','RK2','RK4'};

                        end


                end

                    T = array2table(tabela);
                    app.UITable.Data = T;
                    app.UITable.ColumnName = nomeColunas;
        
                    hold(app.UIAxes,"off")
                    grid(app.UIAxes,"on")
                    legend(app.UIAxes,legenda)


              end
          

          catch ME
                errordlg(ME.message,"Erro","modal")
          end
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {786, 786};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {316, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end

        % Callback function
        function mSairMenuSelected(app, event)
            
        end

        % Menu selected function: SairMenu
        function SairMenuSelected(app, event)
            SN = questdlg("Tem a certeza que pretende mesmo Sair?","Sair","Sim","Não","Sim");
            if strcmp(SN,"Sim")
                delete(app.UIFigure)
            end
        end

        % Menu selected function: AjudaMenu
        function AjudaMenuSelected(app, event)
            msgbox('A seguinte aplicação tem por objeto a resolução de sistemas de equações diferenciais com condições iniciais.')
        end

        % Menu selected function: RelatrioMenu
        function RelatrioMenuSelected(app, event)
           open("Relatorio_AM2_Final.pdf")
        end

        % Menu selected function: AutoresMenu
        function AutoresMenuSelected(app, event)
          run("app_Autores.mlapp")
        end

        % Button pushed function: reset
        function resetButtonPushed(app, event)
            startupFcn(app); %chama funçao que executa logo no inicio da app           
        end

        % Value changed function: eA
        function eAValueChanged(app, event)

        end

        % Value changed function: eF
        function eFValueChanged(app, event)

        end

        % Button down function: DadosPVI
        function DadosPVIButtonDown(app, event)
            
        end

        % Value changed function: DropDown
        function DropDownValueChanged(app, event)
            value = app.DropDown.Value;
            switch value
                case 'Pêndulo'
                    app.eA.Value = 0;
                    app.eB.Value = 15;
                    app.eN.Value = 100;
                    app.e_V.Value = 0;
                    app.e_U.Value = pi/2;
                    app.eG.Value = "-sin(u)-0.3*v";
                    bAtualizarButtonPushed(app, event);
                case 'Sistema Mola-Massa S/Amortecimento'
                    app.eA.Value = 0;
                    app.eB.Value = 15;
                    app.eN.Value = 100;
                    app.e_V.Value = 0;
                    app.e_U.Value = 9;
                    app.eG.Value = "-16*u";
                    bAtualizarButtonPushed(app, event);
                case 'Sistema Mola-Massa C/Amortecimento'
                    app.eA.Value = 0;
                    app.eB.Value = 2;
                    app.eN.Value = 20;
                    app.e_V.Value = -4;
                    app.e_U.Value = 0;
                    app.eG.Value = "-10*v-25*u";
                    bAtualizarButtonPushed(app, event);
                case 'Modelo Vibratório Mecânico'
                    app.eA.Value = 0;
                    app.eB.Value = 15;
                    app.eN.Value = 100;
                    app.e_V.Value = 3;
                    app.e_U.Value = 0;
                    app.eG.Value = "-2*v-2*u+4*cos(t)+2*sin(t)";
                    bAtualizarButtonPushed(app, event);
                case 'Circuito Elétrico'
                    app.eA.Value = 0;
                    app.eB.Value = 10;
                    app.eN.Value = 100;
                    app.e_V.Value = 0;
                    app.e_U.Value = 0;
                    app.eG.Value = "-5*v-4*u+2*exp(-t/4)";
                    bAtualizarButtonPushed(app, event);
                
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.DadosPVI = uipanel(app.LeftPanel);
            app.DadosPVI.Title = 'Sistemas Diferenciais com PVI';
            app.DadosPVI.ButtonDownFcn = createCallbackFcn(app, @DadosPVIButtonDown, true);
            app.ftyLabel = uilabel(app.DadosPVI);
            app.ftyLabel.Text = 'f(t,u,v) =';
            app.eF = uieditfield(app.DadosPVI, 'text');
            app.eF.ValueChangedFcn = createCallbackFcn(app, @eFValueChanged, true);
            app.eF.Editable = 'off';
            app.eF.Value = 'v';
            app.Lamp_f = uilamp(app.DadosPVI);
            app.Lamp_g = uilamp(app.DadosPVI);
            app.eG = uieditfield(app.DadosPVI, 'text');
            app.eG.Value = '-sin(u)-0.3*v';
            app.ftyLabel_2 = uilabel(app.DadosPVI);
            app.ftyLabel_2.Text = 'g(t,u,v) =';
            app.bgMetodos = uibuttongroup(app.LeftPanel);
            app.bgMetodos.Title = 'Métodos ';
            app.rbEuler = uiradiobutton(app.bgMetodos);
            app.rbEuler.Text = 'Euler';
            app.rbEuler.Value = true;
            app.rbRK2 = uiradiobutton(app.bgMetodos);
            app.rbRK2.Text = 'RK2';
            app.rbRK4 = uiradiobutton(app.bgMetodos);
            app.rbRK4.Text = 'RK4';
            app.rbTodos = uiradiobutton(app.bgMetodos);
            app.rbTodos.Text = 'Todos os Métodos';
            app.rbHeun = uiradiobutton(app.bgMetodos);
            app.rbHeun.Text = 'Heun';
            app.bAtualizar = uibutton(app.LeftPanel, 'push');
            app.bAtualizar.ButtonPushedFcn = createCallbackFcn(app, @bAtualizarButtonPushed, true);
            app.bAtualizar.Text = 'Atualizar';
            app.reset = uibutton(app.LeftPanel, 'push');
            app.reset.ButtonPushedFcn = createCallbackFcn(app, @resetButtonPushed, true);
            app.reset.Text = 'Resetar';
            app.DadosPVI_2 = uipanel(app.LeftPanel);
            app.DadosPVI_2.Title = 'Condições Iniciais';
            app.aLabel_7 = uilabel(app.DadosPVI_2);
            app.aLabel_7.Text = 'u0';
            app.e_U = uieditfield(app.DadosPVI_2, 'numeric');
            app.e_U.Value = 1.5707963267949;
            app.aLabel_8 = uilabel(app.DadosPVI_2);
            app.aLabel_8.Text = 'v0';
            app.e_V = uieditfield(app.DadosPVI_2, 'numeric');
            app.Lamp_u0 = uilamp(app.DadosPVI_2);
            app.Lamp_v0 = uilamp(app.DadosPVI_2);
            app.DadosPVI_3 = uipanel(app.LeftPanel);
            app.DadosPVI_3.Title = 'Intervalo';
            app.Lamp_a = uilamp(app.DadosPVI_3);
            app.aLabel = uilabel(app.DadosPVI_3);
            app.aLabel.Text = 'a';
            app.eA = uieditfield(app.DadosPVI_3, 'numeric');
            app.eA.ValueChangedFcn = createCallbackFcn(app, @eAValueChanged, true);
            app.aLabel_2 = uilabel(app.DadosPVI_3);
            app.aLabel_2.Text = 'b';
            app.eB = uieditfield(app.DadosPVI_3, 'numeric');
            app.eB.Value = 15;
            app.Lamp_b = uilamp(app.DadosPVI_3);
            app.aLabel_9 = uilabel(app.DadosPVI_3);
            app.aLabel_9.Text = 'n';
            app.eN = uieditfield(app.DadosPVI_3, 'numeric');
            app.eN.Value = 100;
            app.Lamp_n = uilamp(app.DadosPVI_3);
            app.DropDownLabel = uilabel(app.LeftPanel);
            app.DropDownLabel.Text = 'Drop Down';
            app.DropDown = uidropdown(app.LeftPanel);
            app.DropDown.Items = {'Pêndulo', 'Sistema Mola-Massa S/Amortecimento', 'Sistema Mola-Massa C/Amortecimento', 'Modelo Vibratório Mecânico', 'Circuito Elétrico'};
            app.DropDown.ValueChangedFcn = createCallbackFcn(app, @DropDownValueChanged, true);
            app.DropDown.Value = 'Pêndulo';
            app.RightPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.RightPanel);
            title(app.UIAxes, 'Gráficos')
            xlabel(app.UIAxes, 't')
            ylabel(app.UIAxes, 'y')
            zlabel(app.UIAxes, 'Z')
            app.UITable = uitable(app.RightPanel);
            app.UITable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable.RowName = {};
            app.Menu = uimenu(app.UIFigure);
            app.Menu.Text = '| Menu |';
            app.AjudaMenu = uimenu(app.Menu);
            app.AjudaMenu.MenuSelectedFcn = createCallbackFcn(app, @AjudaMenuSelected, true);
            app.AjudaMenu.Text = '| Ajuda';
            app.RelatrioMenu = uimenu(app.Menu);
            app.RelatrioMenu.MenuSelectedFcn = createCallbackFcn(app, @RelatrioMenuSelected, true);
            app.RelatrioMenu.Text = '| Relatório';
            app.SairMenu = uimenu(app.Menu);
            app.SairMenu.MenuSelectedFcn = createCallbackFcn(app, @SairMenuSelected, true);
            app.SairMenu.Text = '| Sair';
            app.AutoresMenu = uimenu(app.UIFigure);
            app.AutoresMenu.MenuSelectedFcn = createCallbackFcn(app, @AutoresMenuSelected, true);
            app.AutoresMenu.Text = 'Autores |';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = appMaquinaPVI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end